classdef NumericalMethodsSolver < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RESULTTEXTAREATextArea        matlab.ui.control.TextArea
        RESULTTEXTAREATextAreaLabel   matlab.ui.control.Label
        RESULTLabel                   matlab.ui.control.Label
        CALCULATEButton               matlab.ui.control.Button
        FUNCTIONEditField             matlab.ui.control.EditField
        FUNCTIONSLabel                matlab.ui.control.Label
        FINALXEditField               matlab.ui.control.NumericEditField
        FINALXEditFieldLabel          matlab.ui.control.Label
        TOLERANCEEditField            matlab.ui.control.NumericEditField
        TOLERANCEEditFieldLabel       matlab.ui.control.Label
        hEditField                    matlab.ui.control.NumericEditField
        hEditFieldLabel               matlab.ui.control.Label
        INITIALYEditField             matlab.ui.control.NumericEditField
        INITIALYEditFieldLabel        matlab.ui.control.Label
        INITIALXEditField             matlab.ui.control.NumericEditField
        INITIALXEditFieldLabel        matlab.ui.control.Label
        INITIALGUESSX0EditField       matlab.ui.control.NumericEditField
        INITIALGUESSX0EditFieldLabel  matlab.ui.control.Label
        UPPERBOUNDEditField           matlab.ui.control.NumericEditField
        UPPERBOUNDEditFieldLabel      matlab.ui.control.Label
        LOWERBOUNDEditField           matlab.ui.control.NumericEditField
        LOWERBOUNDEditFieldLabel      matlab.ui.control.Label
        NUMERICALMETHODSSOLVERLabel   matlab.ui.control.Label
        METHODSropDown                matlab.ui.control.DropDown
        METHODSDropDownLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)

    method = app.MethodsDropDown.Value;
    funcStr = app.FunctionEditField.Value;
    tol = app.ToleranceEditField.Value;
    
    resultStr = '';
    
    try
        switch method
            case 'BISECTION'
                xl = app.LowerBoundEditField.Value;
                xu = app.UpperBoundEditField.Value;
                f = str2func(['@(x)' funcStr]);
                
                if f(xl) * f(xu) > 0
                    resultStr = 'Error: f(xl) and f(xu) must have opposite signs.';
                else
                    xr = xl;
                    iter = 0;
                    maxIter = 100;
                    err = 100;
                    while err > tol && iter < maxIter
                        xr_old = xr;
                        xr = (xl + xu) / 2;
                        iter = iter + 1;
                        if xr ~= 0
                            err = abs((xr - xr_old) / xr) * 100;
                        end
                        if f(xl) * f(xr) < 0
                            xu = xr;
                        else
                            xl = xr;
                        end
                    end
                    resultStr = sprintf("Method: Bisection\nRoot: %.6f\nIterations: %d\nFinal Error: %.6e", xr, iter, err);
                end
                
            case 'NEWTON-RAPHSON'
                x0 = app.InitialGuessEditField.Value;
                syms x;
                f_sym = str2sym(funcStr);
                df_sym = diff(f_sym, x);
                f = matlabFunction(f_sym);
                df = matlabFunction(df_sym);
                
                iter = 0;
                maxIter = 100;
                err = 100;
                xi = x0;
                
                while err > tol && iter < maxIter
                    x_next = xi - f(xi)/df(xi);
                    iter = iter + 1;
                    err = abs(x_next - xi);
                    xi = x_next;
                end
                resultStr = sprintf("Method: Newton-Raphson\nRoot: %.6f\nIterations: %d\nError: %.6e", xi, iter, err);
                
            case 'SECANT'
                x0 = app.InitialGuessEditField.Value;
                x1 = app.InitialXEditField.Value;
                f = str2func(['@(x)' funcStr]);
                
                iter = 0;
                maxIter = 100;
                err = 100;
                
                while err > tol && iter < maxIter
                    x2 = x1 - (f(x1)*(x1 - x0)) / (f(x1) - f(x0));
                    err = abs(x2 - x1);
                    x0 = x1;
                    x1 = x2;
                    iter = iter + 1;
                end
                resultStr = sprintf("Method: Secant\nRoot: %.6f\nIterations: %d\nError: %.6e", x1, iter, err);
                
            case 'RKA'
                % 4th Order Runge-Kutta for dy/dx = f(x, y)
                f = str2func(['@(x,y)' funcStr]);
                x0 = app.InitialXEditField.Value;
                y0 = app.InitialYEditField.Value;
                h  = app.HEditField.Value;
                xf = app.FinalXEditField.Value;
                
                x = x0;
                y = y0;
                n = ceil((xf - x0) / h);
                
                for i = 1:n
                    k1 = f(x, y);
                    k2 = f(x + 0.5*h, y + 0.5*k1*h);
                    k3 = f(x + 0.5*h, y + 0.5*k2*h);
                    k4 = f(x + h, y + k3*h);
                    
                    y = y + (h/6)*(k1 + 2*k2 + 2*k3 + k4);
                    x = x + h;
                end
                resultStr = sprintf("Method: 4th Order Runge-Kutta (RKA)\nFinal X: %.4f\nFinal Y: %.6f", x, y);
        end
    catch ME
        resultStr = sprintf("Calculation Error:\n%s", ME.message);
    end
    
    app.ResultTextArea.Value = resultStr;
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.METHODSDropDownLabel = uilabel(app.UIFigure);
            app.METHODSDropDownLabel.Text = 'METHODS:';
            app.METHODSropDown = uidropdown(app.UIFigure);
            app.METHODSropDown.Items = {'BISECTION', 'NEWTON-RAPHSON', 'SECANT', 'RKA'};
            app.METHODSropDown.Value = 'BISECTION';
            app.NUMERICALMETHODSSOLVERLabel = uilabel(app.UIFigure);
            app.NUMERICALMETHODSSOLVERLabel.Text = 'NUMERICAL METHODS SOLVER';
            app.LOWERBOUNDEditFieldLabel = uilabel(app.UIFigure);
            app.LOWERBOUNDEditFieldLabel.Text = 'LOWER BOUND:';
            app.LOWERBOUNDEditField = uieditfield(app.UIFigure, 'numeric');
            app.UPPERBOUNDEditFieldLabel = uilabel(app.UIFigure);
            app.UPPERBOUNDEditFieldLabel.Text = 'UPPER BOUND:';
            app.UPPERBOUNDEditField = uieditfield(app.UIFigure, 'numeric');
            app.UPPERBOUNDEditField.Value = 1;
            app.INITIALGUESSX0EditFieldLabel = uilabel(app.UIFigure);
            app.INITIALGUESSX0EditFieldLabel.Text = 'INITIAL GUESS (X0):';
            app.INITIALGUESSX0EditField = uieditfield(app.UIFigure, 'numeric');
            app.INITIALXEditFieldLabel = uilabel(app.UIFigure);
            app.INITIALXEditFieldLabel.Text = 'INITIAL X:';
            app.INITIALXEditField = uieditfield(app.UIFigure, 'numeric');
            app.INITIALXEditField.Value = 1;
            app.INITIALYEditFieldLabel = uilabel(app.UIFigure);
            app.INITIALYEditFieldLabel.Text = 'INITIAL Y:';
            app.INITIALYEditField = uieditfield(app.UIFigure, 'numeric');
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Text = 'h:';
            app.hEditField = uieditfield(app.UIFigure, 'numeric');
            app.hEditField.Value = 2;
            app.TOLERANCEEditFieldLabel = uilabel(app.UIFigure);
            app.TOLERANCEEditFieldLabel.Text = 'TOLERANCE';
            app.TOLERANCEEditField = uieditfield(app.UIFigure, 'numeric');
            app.TOLERANCEEditField.Value = 1e-05;
            app.FINALXEditFieldLabel = uilabel(app.UIFigure);
            app.FINALXEditFieldLabel.Text = 'FINAL X:';
            app.FINALXEditField = uieditfield(app.UIFigure, 'numeric');
            app.FINALXEditField.Value = 4;
            app.FUNCTIONSLabel = uilabel(app.UIFigure);
            app.FUNCTIONSLabel.Text = 'FUNCTION:';
            app.FUNCTIONEditField = uieditfield(app.UIFigure, 'text');
            app.FUNCTIONEditField.Value = 'X^3-X-2';
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.RESULTLabel = uilabel(app.UIFigure);
            app.RESULTLabel.Text = 'RESULT';
            app.RESULTTEXTAREATextAreaLabel = uilabel(app.UIFigure);
            app.RESULTTEXTAREATextAreaLabel.Text = 'RESULT TEXT AREA';
            app.RESULTTEXTAREATextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NumericalMethodsSolver

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end